classdef HlasoveOvladani < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Lamp            matlab.ui.control.Lamp
        Label           matlab.ui.control.Label
        EditField       matlab.ui.control.EditField
        EditFieldLabel  matlab.ui.control.Label
        StartButton     matlab.ui.control.Button
        SignalAxes      matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = private)
        CommandList;
        Robot
        Fs;
        Threshold;
        SilenceFramesRequired;
        VoiceRecorder;
        TrainedModel;
        IsRunning;
    end

    methods (Access = private)

        function updatePlot(app, audioData, titleText)
            if isa(audioData, 'int16')
                plotData = double(audioData) / 32768.0;
            else
                plotData = audioData;
            end
            t = (0:length(plotData)-1) / app.Fs;
            plot(app.SignalAxes, t, plotData);
            title(app.SignalAxes, titleText);
            xlabel(app.SignalAxes, 'Čas (s)');
            grid(app.SignalAxes, 'on');
            ylim(app.SignalAxes, [-1.1 1.1]);
        end


        function word = readHTKResult(~, filePath)
            word = 'NEROZPOZNÁNO';
            if exist(filePath, 'file')
                text = fileread(filePath);
                tokens = regexp(text, '\s+', 'split');
                idx = find(strcmp(tokens, 'SENT-START'), 1);
                if ~isempty(idx)
                    for i = idx+1 : length(tokens)
                        tok = tokens{i};
                        if ~isnan(str2double(tok))
                            continue;
                        end
                        if strcmp(tok, 'SENT-END') || strcmp(tok, '.')
                            break;
                        end
                        word = tok;
                        break;
                    end
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % init Java Robot
            import java.awt.Robot;
            import java.awt.event.KeyEvent;
            app.Robot = java.awt.Robot();

            %Vars
            app.Fs = 16000;
            app.Threshold=0.2;
            app.SilenceFramesRequired = 12; %10 až 20 ?

            %Objects
            app.VoiceRecorder = audiorecorder(app.Fs, 16, 1, -1);
        end

        % Button pushed function: StartButton
        function StartButtonButtonPushed(app, event)
            import java.awt.event.KeyEvent;

            % --- LOGIKA PRO ZASTAVENÍ ---
            if app.IsRunning
                app.IsRunning = false;
                app.StartButton.Text = "Spustit hlasové ovládání";
                app.StartButton.BackgroundColor = [0.96, 0.96, 0.96];
                app.Lamp.Color = [0.8 0.8 0.8];
                app.EditField.Value = "";
                disp("Hlasové ovládání zastaveno.");
                stop(app.VoiceRecorder);
                return;
            end

            % --- LOGIKA PRO SPUŠTĚNÍ ---
            app.StartButton.Text = "ZASTAVIT";
            app.StartButton.BackgroundColor = [1, 0, 0];
            app.IsRunning = true;
            app.EditField.Value = "Klikni do Kalkulačky!";
            drawnow;
            pause(2); % Čas pro překliknutí uživatele
            app.EditField.Value = "Čekám na slovo...";
            app.Lamp.Color = [0 1 0]; % Zelená = Čekám
            drawnow;

            % Vytvoření souboru pro HTK
            fid = fopen('live.list', 'w');
            fprintf(fid, 'live_input.wav live_input.mfc\n');
            fclose(fid);

            % Spuštění mikrofonu na pozadí
            record(app.VoiceRecorder);

            % Proměnné pro FSM
            isSpeaking = false;
            speechBuffer = [];
            silenceCounter = 0;
            lastReadSample = 1;
            visualBuffer = zeros(app.Fs, 1);% Pro vykreslování v grafu (1 vteřina)


            while app.IsRunning
                drawnow
                pause(0.05); %naladit 50-100 ms

                totalSamples = app.VoiceRecorder.TotalSamples;
                if totalSamples < lastReadSample + (0.05 * app.Fs)
                    continue; % Ještě není dost nových dat, počkáme
                end

                audioIn = getaudiodata(app.VoiceRecorder);
                newData = audioIn(lastReadSample:end);
                lastReadSample = totalSamples;

                % Aktualizace vizuálního grafu
                visualBuffer = [visualBuffer(length(newData)+1:end); newData];
                plot(app.SignalAxes, (1:length(visualBuffer))/app.Fs, visualBuffer);
                ylim(app.SignalAxes, [-1 1]);
                drawnow;

                currentEnergy = max(abs(audioIn));
                % audioLength = length(audioIn);

                if ~isSpeaking % ČEKÁNÍ NA ŘEČ              
                    if currentEnergy > app.Threshold
                        isSpeaking = true;
                        app.Lamp.Color = [1 0 0];
                        app.EditField.Value = "Mluvíš...";
                        speechBuffer = newData;
                        silenceCounter = 0;
                    end

                else
                    % STAV: PRÁVĚ MLUVÍŠ A NAHRÁVÁME
                    speechBuffer = [speechBuffer; newData]; % Přidáváme data

                    % if currentEnergy < app.Threshold
                    %     silenceCounter = silenceCounter + 1; % Je ticho
                    % else
                    %     silenceCounter = 0; % Zase je to nahlas, resetujeme ticho
                    % end

                    silenceCounter = silenceCounter + 1;

                    % Pokud je ticho už dostatečně dlouhé -> SLOVO JE U KONCE!
                    if silenceCounter >= app.SilenceFramesRequired
                        disp(['Počítadlo ticha: ', num2str(silenceCounter)]);
                        app.Lamp.Color = [1 1 0]; % Žlutá = Zpracovávám
                        app.EditField.Value = "Zpracovávám...";
                        drawnow;

                        % Ořízneme to dlouhé ticho na konci (necháme jen malý buffer)
                        margin = round(0.15 * app.Fs);
                        if length(speechBuffer) > margin
                            speechBuffer = speechBuffer(1 : end - (silenceCounter * 0.05 * app.Fs) + margin);
                        end

                        % Vykreslení signálu do grafu ---
                        app.updatePlot(audioIn, 'Aktuální nahrávka');

                        currentEnergy = max(abs(audioIn));
                        if currentEnergy < app.Threshold
                            app.EditField.Value = "Ticho...";
                            app.EditField.FontColor = [0.5 0.5 0.5];
                            continue;
                        end

                        app.EditField.Value = "Zpracovávám...";

                        % Normalizace a uložení
                        audioFinal = audioIn / max(abs(audioIn));
                        audiowrite('live_input.wav', audioFinal, app.Fs);

                        % Smazání starého výsledeku
                        if exist('live_res.mlf', 'file')
                            delete('live_res.mlf');
                        end

                        % Ošetřené volání HTK
                        [stat1, out1] = system('HCopy -T 1 -C ParamConfig-MFCC39 -S live.list');
                        if stat1 ~= 0
                            disp('CHYBA V HCopy (Parametrizace):'); disp(out1);
                        end

                        [stat2, out2] = system('HVite -H hmm3/macros -H hmm3/hmmdefs -i live_res.mlf -w net -p 0.0 -s 5.0 dict models0 live_input.mfc');
                        if stat2 ~= 0
                            disp('CHYBA V HVite (Rozpoznávání):'); disp(out2);
                        end

                        % Přečtení výsledku z HTK
                        rozpoznaneSlovo = app.readHTKResult('live_res.mlf');
                        fprintf('Rozpoznáno: %s\n', rozpoznaneSlovo);
                        app.EditField.Value = string(rozpoznaneSlovo);
                        app.EditField.FontColor = [0 0.5 0];

                        %Provedení akce - ZMÁČKNUTÍ KLÁVESY
                        try
                            switch upper(rozpoznaneSlovo)
                                case 'JEDNA'
                                    app.Robot.keyPress(KeyEvent.VK_NUMPAD1);
                                    app.Robot.keyRelease(KeyEvent.VK_NUMPAD1);
                                case 'DVA'
                                    app.Robot.keyPress(KeyEvent.VK_NUMPAD2);
                                    app.Robot.keyRelease(KeyEvent.VK_NUMPAD2);
                                case 'TRI'
                                    app.Robot.keyPress(KeyEvent.VK_NUMPAD3);
                                    app.Robot.keyRelease(KeyEvent.VK_NUMPAD3);
                                case 'CTYRI'
                                    app.Robot.keyPress(KeyEvent.VK_NUMPAD4);
                                    app.Robot.keyRelease(KeyEvent.VK_NUMPAD4);
                                case 'PET'
                                    app.Robot.keyPress(KeyEvent.VK_NUMPAD5);
                                    app.Robot.keyRelease(KeyEvent.VK_NUMPAD5);
                                case 'SEST'
                                    app.Robot.keyPress(KeyEvent.VK_NUMPAD6);
                                    app.Robot.keyRelease(KeyEvent.VK_NUMPAD6);
                                case 'SEDM'
                                    app.Robot.keyPress(KeyEvent.VK_NUMPAD7);
                                    app.Robot.keyRelease(KeyEvent.VK_NUMPAD7);
                                case 'OSM'
                                    app.Robot.keyPress(KeyEvent.VK_NUMPAD8);
                                    app.Robot.keyRelease(KeyEvent.VK_NUMPAD8);
                                case 'DEVET'
                                    app.Robot.keyPress(KeyEvent.VK_NUMPAD9);
                                    app.Robot.keyRelease(KeyEvent.VK_NUMPAD9);
                                case 'NULA'
                                    app.Robot.keyPress(KeyEvent.VK_NUMPAD0);
                                    app.Robot.keyRelease(KeyEvent.VK_NUMPAD0);
                                case 'PLUS'
                                    app.Robot.keyPress(KeyEvent.VK_ADD);
                                    app.Robot.keyRelease(KeyEvent.VK_ADD);
                                case 'MINUS'
                                    app.Robot.keyPress(KeyEvent.VK_SUBTRACT);
                                    app.Robot.keyRelease(KeyEvent.VK_SUBTRACT);
                                case 'KRAT'
                                    app.Robot.keyPress(KeyEvent.VK_MULTIPLY);
                                    app.Robot.keyRelease(KeyEvent.VK_MULTIPLY);
                                case 'DELENO'
                                    app.Robot.keyPress(KeyEvent.VK_DIVIDE);
                                    app.Robot.keyRelease(KeyEvent.VK_DIVIDE);
                                case 'ROVNASE'
                                    app.Robot.keyPress(KeyEvent.VK_ENTER);
                                    app.Robot.keyRelease(KeyEvent.VK_ENTER);
                                case 'SMAZAT'
                                    app.Robot.keyPress(KeyEvent.VK_BACK_SPACE);
                                    app.Robot.keyRelease(KeyEvent.VK_BACK_SPACE);
                                case 'VYMAZAT'
                                    app.Robot.keyPress(KeyEvent.VK_ESCAPE);
                                    app.Robot.keyRelease(KeyEvent.VK_ESCAPE); % Escape maže celou kalkulačku
                                case 'PLUSMINUS'
                                    % Klávesová zkratka pro +/- ve Win kalkulačce je obvykle F9
                                    app.Robot.keyPress(KeyEvent.VK_F9);
                                    app.Robot.keyRelease(KeyEvent.VK_F9);
                                case 'CARKA'
                                    % Desetinná čárka
                                    app.Robot.keyPress(KeyEvent.VK_COMMA);
                                    app.Robot.keyRelease(KeyEvent.VK_COMMA);
                                case 'NADRUHOU'
                                    % Klávesová zkratka je 'Q'
                                    app.Robot.keyPress(KeyEvent.VK_Q);
                                    app.Robot.keyRelease(KeyEvent.VK_Q);
                                case 'ODMOCNINA'
                                    % Klávesová zkratka je '@'
                                    app.Robot.keyPress(KeyEvent.VK_CONTROL);
                                    app.Robot.keyPress(KeyEvent.VK_ALT);
                                    app.Robot.keyPress(KeyEvent.VK_V);

                                    app.Robot.keyRelease(KeyEvent.VK_V);
                                    app.Robot.keyRelease(KeyEvent.VK_ALT);
                                    app.Robot.keyRelease(KeyEvent.VK_CONTROL);
                                case 'ZPET'
                                    % Pokud to znamená krok zpět
                                    app.Robot.keyPress(KeyEvent.VK_BACK_SPACE);
                                    app.Robot.keyRelease(KeyEvent.VK_BACK_SPACE);
                            end
                        catch
                            disp('Chyba při stisku klávesy.');
                        end

                        % --- RESET PRO DALŠÍ SLOVO ---
                        isSpeaking = false;
                        speechBuffer = [];

                        % Vyčištění paměti rekordéru
                        stop(app.VoiceRecorder);
                        record(app.VoiceRecorder);
                        lastReadSample = 1;
                        app.Lamp.Color = [0 1 0];
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SignalAxes = uiaxes(app.UIFigure);
            title(app.SignalAxes, 'Title')
            xlabel(app.SignalAxes, 'Čas(s)')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonButtonPushed, true);
            app.StartButton.Text = 'Spustit hlasové ovládání';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HlasoveOvladani

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end